classdef depthdetection_toolbox2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout_Main            matlab.ui.container.GridLayout
        GridLayout_Menu            matlab.ui.container.GridLayout
        DropDown_SelectWindowConf  matlab.ui.control.DropDown
        GridLayout7                matlab.ui.container.GridLayout
        Button_ResetWindowConf     matlab.ui.control.Button
        Button_WindowExport        matlab.ui.control.Button
        TimestampLabel             matlab.ui.control.Label
        AnimationLabel_2           matlab.ui.control.Label
        GridLayout6                matlab.ui.container.GridLayout
        Button_TimestampNext       matlab.ui.control.Button
        Button_TimestampPrev       matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        Label_Timestamp            matlab.ui.control.Label
        EditField_Timestamp        matlab.ui.control.NumericEditField
        Button_DepthExport         matlab.ui.control.Button
        Button_DepthDetect         matlab.ui.control.Button
        Label_WindowConf           matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        Button_TimestampPause      matlab.ui.control.Button
        Button_TimestampPlay       matlab.ui.control.Button
        GridLayout                 matlab.ui.container.GridLayout
        Button_TransducerNext      matlab.ui.control.Button
        Button_TransducerPrev      matlab.ui.control.Button
        Button_LoadUSData          matlab.ui.control.Button
        DropDown_Measurement       matlab.ui.control.DropDown
        RecordLabel                matlab.ui.control.Label
        DropDown_Trial             matlab.ui.control.DropDown
        TrialDropDownLabel         matlab.ui.control.Label
        Spinner_MinPeakDist        matlab.ui.control.Spinner
        MinPeakDistSpinnerLabel    matlab.ui.control.Label
        Spinner_MinPeakDiff        matlab.ui.control.Spinner
        MinPeakDiffLabel           matlab.ui.control.Label
        Spinner_MinProm            matlab.ui.control.Spinner
        MinPromLabel               matlab.ui.control.Label
        Spinner_MinHeight          matlab.ui.control.Spinner
        MinHeightSpinnerLabel      matlab.ui.control.Label
        Spinner_WindowWidth        matlab.ui.control.Spinner
        WidthLabel                 matlab.ui.control.Label
        Spinner_WindowCenter       matlab.ui.control.Spinner
        CenterLabel                matlab.ui.control.Label
        DropDown_TransducerNumber  matlab.ui.control.DropDown
        TransducerDropDownLabel    matlab.ui.control.Label
        GridLayout_Graphs          matlab.ui.container.GridLayout
        GridLayout_Slider          matlab.ui.container.GridLayout
        Slider_Timestamp           matlab.ui.control.Slider
        UIAxes_Mmode               matlab.ui.control.UIAxes
        UIAxes_Amode               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path_settings = struct();   % all related to paths
        ust_usspec    = struct();   % ultrasound spesification detail
        ust_dataspec  = struct();   % spesification of the ultrasound data
        ust_windowconfig    = struct();   % specification of the transducer

        ust_datavalues;              % stores all ultrasound data

        % these two are used to prevent the spinner goes under limit
        spinner_windowcenter_valueprev;  % Previous valid value for the window center spinner
        spinner_windowwidth_valueprev;   % Previous valid value for the window width spinner
    end
    
    methods (Access = public)

        function [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, window)
            % trim the signal
            lb_idx = round(window.LowerBound / app.ust_usspec.ds);
            ub_idx = round(window.UpperBound / app.ust_usspec.ds);

            % check if the lowerbound and upperbound is zero
            if(lb_idx==0 || ub_idx==0)
                peak_mm  = [];
                peak_val = [];
                return;
            end

            % if not, let's trim the signal
            trimmed_usdata = app.ust_datavalues(selected_ust, lb_idx:ub_idx, selected_timestamp);

            % peak detection (and get data from GUI)
            min_height   = app.Spinner_MinHeight.Value;
            min_prom     = app.Spinner_MinProm.Value;
            min_peakdiff = app.Spinner_MinPeakDiff.Value;
            min_peakdist = app.Spinner_MinPeakDist.Value;
            [peakinwindow_val, peakinwindow_idx] = findpeaks( trimmed_usdata, ...
                                                            'NPeaks', 1, ...
                                                            'SortStr', 'descend', ...
                                                            'MinPeakHeight', min_height, ...
                                                            'MinPeakProminence', min_prom, ...
                                                            'Threshold', min_peakdiff, ...
                                                            'MinPeakDistance', min_peakdist);
            % check if peak is detected
            if(isempty(peakinwindow_idx))
                peak_mm  = [];
                peak_val = [];
                return;
            end

            % convert peak in window to peak in the whole signal
            peak_idx = lb_idx + peakinwindow_idx;
            peak_mm  = peak_idx * app.ust_usspec.ds;
            peak_val = peakinwindow_val;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Path Settings
            
            % store necessary things to global path_settings
            path_settings.path_root     = 'D:\Documents\BELANDA\PhD Thesis\Code\MATLAB\amode_navigation_experiment\experiment_b'; %#ok<*ADPROP>
            path_settings.path_function = fullfile(path_settings.path_root, 'functions');
            path_settings.path_outputs  = fullfile(path_settings.path_root, 'outputs');
            path_settings.path_data     = fullfile(path_settings.path_root, 'data');
            app.path_settings           = path_settings;

            % generate the path function
            addpath(genpath(path_settings.path_function));

            %% Ultrasound data specification

            % declare ultrasound data
            ust_dataspec.n_ust     = 30;
            ust_dataspec.n_sample  = 3500;
            app.ust_dataspec = ust_dataspec;

            % declare the ultrasound spesification
            ust_usspec.vsound   = 1494;                % [m/s]
            ust_usspec.f        = 50 * 1e6;            % [Hz]
            ust_usspec.dt       = 1/ust_usspec.f;         % [s]
            ust_usspec.nt       = ust_dataspec.n_sample;  % [unit]
            ust_usspec.t_vector = 0:ust_usspec.dt:(ust_usspec.dt*(ust_usspec.nt-1));        % [[s]]
            ust_usspec.ds       = 0.5 * ust_usspec.vsound * ust_usspec.dt * 1e3;         % [mm]
            ust_usspec.s_vector = 0.5 * ust_usspec.vsound * ust_usspec.t_vector * 1e3;   % [[mm]]
            ust_usspec.pitch    = 8;                   % [mm]
            ust_usspec.width    = 6;                   % [mm]
            ust_usspec.kerf     = 2;                   % [mm]
            app.ust_usspec = ust_usspec;

            %% Update to the GUI
            % let's update the dropdown_transducer values
            dropdown_items = "UST " + string(1:ust_dataspec.n_ust);
            app.DropDown_TransducerNumber.Items = dropdown_items;

            % get the name of the trials
            items_dir = dir(path_settings.path_data);
            % filter for directories whose names start with 'trial'
            folders = {items_dir([items_dir.isdir] & startsWith({items_dir.name}, 'trial')).name};
            % set the trials to dropdown menu
            app.DropDown_Trial.Items = folders;

            %% Others
            
            % this is will be useful if user recklessly lowering down the
            % spinner value for window center even below limit
            app.spinner_windowcenter_valueprev = app.Spinner_WindowCenter.Value;
            app.spinner_windowwidth_valueprev = app.Spinner_WindowWidth.Value;

            % amode axis will be drawn with several plot, so let's hold it
            hold(app.UIAxes_Amode, 'on');

            % let's update dropdown_measurement values, i can make benefit
            % of this function
            DropDown_TrialValueChanged(app);
        end

        % Value changed function: DropDown_Trial
        function DropDown_TrialValueChanged(app, event)
            % get the value from the GUI
            dir_trial = app.DropDown_Trial.Value;

            % get the necessary global variable
            path_settings = app.path_settings;

            % set some path
            path_allmeasurement  = fullfile(path_settings.path_root, "data", dir_trial, "measurement");
            path_allintermediate = fullfile(path_settings.path_root, "data", dir_trial, "intermediate");
            path_allbonescan     = fullfile(path_settings.path_root, "data", dir_trial, "bonescan");

            % get the name of the trials
            items_dir = dir(path_allmeasurement);
            % filter for directories whose names start with 'trial'
            folders = {items_dir([items_dir.isdir] & ~ismember({items_dir.name}, {'.', '..'})).name};
            % set the trials to dropdown menu
            app.DropDown_Measurement.Items = folders;

            % store necessary things to global path_settings
            path_settings.path_allmeasurement  = path_allmeasurement;
            path_settings.path_allintermediate = path_allintermediate;
            path_settings.path_allbonescan     = path_allbonescan;
            app.path_settings = path_settings;

            % read the window data and update the dropdown
            csv_files = dir(fullfile(path_settings.path_allintermediate, '*.csv')); % get all csv files
            csv_names = {csv_files.name};                                           % extract the names of the CSV file
            app.DropDown_SelectWindowConf.Items = csv_names;                        % set all the configuration files name to the drop down
        end

        % Button pushed function: Button_LoadUSData
        function Button_LoadUSDataPushed(app, event)
            % get some necessary global variables
            path_settings = app.path_settings; %#ok<*ADPROPLC>
            ust_dataspec   = app.ust_dataspec;
            ust_usspec       = app.ust_usspec;

            % get the necessary dirs
            dir_intermediate = app.DropDown_Measurement.Value;
            dir_measurement  = app.DropDown_Measurement.Value;
            path_intermediate = fullfile(path_settings.path_allintermediate, dir_intermediate);
            path_measurement  = fullfile(path_settings.path_allmeasurement, dir_measurement);

            % read the ultrasound data
            [ust_datavalues, ~, ~] = readTIFF_USsignal(path_measurement, ust_dataspec.n_ust, ust_dataspec.n_sample);
            n_timestamp = size(ust_datavalues, 3);


            %%
            % display the mmode image
            selected_ust       = app.DropDown_TransducerNumber.ValueIndex;
            mmode_matrix       = squeeze(ust_datavalues(selected_ust, :, :));
            mmode_imagethresh  = 10000;
            display_mmode(app.UIAxes_Mmode, mmode_matrix, mmode_imagethresh, [1 n_timestamp], [ust_usspec.s_vector(1), ust_usspec.s_vector(end)]);

            % display a-mode
            selected_timestamp = 1;
            amode_signal       = squeeze( ust_datavalues(selected_ust, :, selected_timestamp) );
            display_amode(app.UIAxes_Amode, ust_usspec.s_vector, amode_signal, 10e3);

            % display the timestamp line in the mmode image
            display_mmode_timestamp(app.UIAxes_Mmode, selected_timestamp);

            %%
            % set the value of n_timestamp, label and slider
            app.Label_Timestamp.Text      = num2str(n_timestamp);
            app.Slider_Timestamp.Limits   = [1, n_timestamp];
            app.EditField_Timestamp.Value = 1;

            % store amode data to global
            app.ust_datavalues   = ust_datavalues;
            % store n_timestamp to ust_usspec in global
            ust_dataspec.n_timestamp = n_timestamp;
            app.ust_dataspec         = ust_dataspec;
            % store path measurement and intermediate to global
            path_settings.path_measurement = path_measurement;
            path_settings.dir_intermediate = dir_intermediate;
            app.path_settings = path_settings;

            %%
            % % read the window data and show the window in the plot
            % % i can use this function
            % Button_ResetWindowConfPushed(app);
            
            % load and show the window in the plot
            % i can use this function
            DropDown_SelectWindowConfValueChanged(app); 
        end

        % Button pushed function: Button_ResetWindowConf
        function Button_ResetWindowConfPushed(app, event)
            % if(isempty(app.ust_datavalues))
            %     return;
            % end
            
            % % get some necessary global variables
            % path_settings = app.path_settings;
            % 
            % % get all csv files
            % csv_files = dir(fullfile(path_settings.path_allintermediate, '*.csv'));
            % % extract the names of the CSV files
            % csv_names = {csv_files.name};
            % % set all the configuration files name to the drop down
            % app.DropDown_SelectWindowConf.Items = csv_names;

            % % if the value more than one dont load it
            % if (numel(csv_names)>1) return; end

            % load and show the window in the plot
            % i can use this function
            % DropDown_SelectWindowConfValueChanged(app);

            % resetting means reloading the window configuration file, it
            % is the same action as if the user change the value in the
            % dropdown, so i will just use that function
            DropDown_SelectWindowConfValueChanged(app)
        end

        % Value changed function: DropDown_SelectWindowConf
        function DropDown_SelectWindowConfValueChanged(app, event)
            if(isempty(app.ust_datavalues))
                return;
            end
            
            % get some necessary global variables
            path_settings = app.path_settings;
            ust_usspec    = app.ust_usspec;            
            
            % get the value in the dropdowns
            selected_windowconf = app.DropDown_SelectWindowConf.Value;
            selected_ust        = app.DropDown_TransducerNumber.ValueIndex;
            selected_timestamp  = app.EditField_Timestamp.Value;
            
            % Read the CSV file into a table.
            csv_file = fullfile(path_settings.path_allintermediate, selected_windowconf);
            ust_windowconfig = table2struct(readtable(csv_file));

            % store the data to global
            app.ust_windowconfig = ust_windowconfig;

            % check if window is valid
            if(~ust_windowconfig(selected_ust).IsSet)
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodewindow'));
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodepeak'));
                uialert(app.UIFigure, 'No window value exist for the current transducer in the loaded window configuration file', 'Warning', 'Icon', 'warning')
                return;
            end

            % set the values
            app.Spinner_WindowCenter.Value  = ust_windowconfig(selected_ust).Middle;
            app.Spinner_WindowWidth.Value   = ust_windowconfig(selected_ust).Middle - ust_windowconfig(selected_ust).LowerBound;
            app.Spinner_WindowCenter.Limits = [0, ust_usspec.s_vector(end)];
            app.Spinner_WindowWidth.Limits  = [0, ust_usspec.s_vector(end)];

            % display the window in a-mode plot and m-mode plot
            lb = ust_windowconfig(selected_ust).LowerBound;
            m  = ust_windowconfig(selected_ust).Middle;
            ub = ust_windowconfig(selected_ust).UpperBound;
            display_amode_window(app.UIAxes_Amode, lb, m, ub);
            display_mmode_window(app.UIAxes_Mmode, lb, m, ub);

            % peak detect (and get the value in the dropdowns)
            selected_ust        = app.DropDown_TransducerNumber.ValueIndex;
            selected_timestamp  = app.EditField_Timestamp.Value;
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, ust_windowconfig(selected_ust));
            % display amode peak
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);

        end

        % Button pushed function: Button_WindowExport
        function Button_WindowExportPushed(app, event)
            % convert our ust_windowconfig to a table
            table = struct2table(app.ust_windowconfig);
            
            % get the current file name
            selected_windowconf      = app.DropDown_SelectWindowConf.Value;
            % edit the current fule name to a new one (suggestion)
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            selected_windowconf_strs = split(selected_windowconf, '.');
            selected_windowconf_new  = [selected_windowconf_strs{1}, '_edited', timestamp, '.csv'];

            % Open save dialog
            [filename, pathname] = uiputfile('*.csv', 'Save CSV File', fullfile(app.path_settings.path_allintermediate, selected_windowconf_new));
            
            % If user didn't cancel
            if ischar(filename)
                fullFileName = fullfile(pathname, filename);
                writetable(table, fullFileName);
                disp(['File saved to: ', fullFileName]);
            else
                disp('User canceled file save.');
            end
        end

        % Button pushed function: Button_TransducerPrev
        function Button_TransducerPrevPushed(app, event)
            % check if the index of DropDown_Transducer already in the beginning
            selected_ust = app.DropDown_TransducerNumber.ValueIndex;
            n_ust        = numel(app.DropDown_TransducerNumber.Items);
            if(selected_ust==1)
                return;
            end

            % minus one 
            app.DropDown_TransducerNumber.Value = app.DropDown_TransducerNumber.Items{selected_ust-1} ;
            
            % show the new mmode image, we can use this function
            DropDown_TransducerNumberValueChanged(app);
        end

        % Button pushed function: Button_TransducerNext
        function Button_TransducerNextPushed(app, event)
            % check if the index of DropDown_Transducer already in the end
            selected_ust = app.DropDown_TransducerNumber.ValueIndex;
            n_ust        = numel(app.DropDown_TransducerNumber.Items);
            if(selected_ust==n_ust)
                return;
            end

            % add one 
            app.DropDown_TransducerNumber.Value = app.DropDown_TransducerNumber.Items{selected_ust+1} ;
            
            % show the new mmode image, we can use this function
            DropDown_TransducerNumberValueChanged(app);
        end

        % Value changed function: DropDown_TransducerNumber
        function DropDown_TransducerNumberValueChanged(app, event)
            % check if the window configuration file is already loaded
            if(isempty(app.ust_windowconfig))
                return;
            end

            % get some necessary global variables
            ust_windowconfig  = app.ust_windowconfig;
            ust_usspec     = app.ust_usspec;
            ust_dataspec = app.ust_dataspec;
            
            % check if the data is already loaded or not
            if(isempty(app.ust_datavalues))
                return;
            end
            
            % display the mmode image
            selected_ust      = app.DropDown_TransducerNumber.ValueIndex;
            mmode_matrix      = squeeze(app.ust_datavalues(selected_ust, :, :));
            mmode_imagethresh = 10000;
            display_mmode(app.UIAxes_Mmode, mmode_matrix, mmode_imagethresh, [1 ust_dataspec.n_timestamp], [ust_usspec.s_vector(1), ust_usspec.s_vector(end)]);

            % get the a-mode data
            selected_timestamp = app.EditField_Timestamp.Value;
            amode_signal = squeeze( app.ust_datavalues(selected_ust, :, selected_timestamp) );
            % display a-mode
            display_amode(app.UIAxes_Amode, ust_usspec.s_vector, amode_signal, 10e3);
            

            % check if window is valid
            if(~ust_windowconfig(selected_ust).IsSet)
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodewindow'));
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodepeak'));
                uialert(app.UIFigure, 'No window value exist for the current transducer in the loaded window configuration file', 'Warning', 'Icon', 'warning')
                return;
            end

            % update the values for windows
            app.Spinner_WindowCenter.Value  = ust_windowconfig(selected_ust).Middle;
            app.Spinner_WindowWidth.Value   = ust_windowconfig(selected_ust).Middle - ust_windowconfig(selected_ust).LowerBound;
            app.Spinner_WindowCenter.Limits = [0, ust_usspec.s_vector(end)];
            app.Spinner_WindowWidth.Limits  = [0, ust_usspec.s_vector(end)];

            % get window data
            lb = ust_windowconfig(selected_ust).LowerBound;
            m  = ust_windowconfig(selected_ust).Middle;
            ub = ust_windowconfig(selected_ust).UpperBound;

            % display the window in a-mode plot
            display_amode_window(app.UIAxes_Amode, lb, m, ub)
            display_mmode_window(app.UIAxes_Mmode, lb, m, ub);
            
            % display the timestamp line in the mmode image
            display_mmode_timestamp(app.UIAxes_Mmode, selected_timestamp);

            % peak detect (and get the value in the dropdowns)
            selected_ust        = app.DropDown_TransducerNumber.ValueIndex;
            selected_timestamp  = app.EditField_Timestamp.Value;
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, ust_windowconfig(selected_ust));
            % display amode peak
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);
        end

        % Button pushed function: Button_TimestampPrev
        function Button_TimestampPrevPushed(app, event)
            % check if the timestamp already in the beginning
            selected_timestamp = app.EditField_Timestamp.Value;
            if(selected_timestamp==1)
                return;
            end
            
            % edit the timestamp text to -1
            app.EditField_Timestamp.Value = app.EditField_Timestamp.Value-1;
            % move the slider timestamp to +1
            app.Slider_Timestamp.Value = app.Slider_Timestamp.Value-1;

            % update the a-mode plot and the mmode timestamp plot
            Slider_TimestampValueChanging(app, event);
        end

        % Button pushed function: Button_TimestampNext
        function Button_TimestampNextPushed(app, event)
            % check if the timestamp already in the end
            selected_timestamp = app.EditField_Timestamp.Value;
            n_timestamp        = str2double(app.Label_Timestamp.Text);
            if(selected_timestamp==n_timestamp)
                return;
            end

            % edit the timestamp text to +1
            app.EditField_Timestamp.Value = app.EditField_Timestamp.Value+1;
            % move the slider timestamp to +1
            app.Slider_Timestamp.Value = app.Slider_Timestamp.Value+1;

            % update the a-mode plot and the mmode timestamp plot
            Slider_TimestampValueChanging(app, event);
        end

        % Value changing function: Slider_Timestamp
        function Slider_TimestampValueChanging(app, event)
            % get current timestamp
            if ( strcmp(event.Source.Type, 'uislider') )
                selected_timestamp = round(event.Value);
            elseif (strcmp(event.Source.Type, 'uibutton') )
                selected_timestamp = round(app.EditField_Timestamp.Value);
            else
                return;
            end

            % change the editfield timestamp according to current timestamp
            app.EditField_Timestamp.Value = selected_timestamp;

            % check if the usdata already loaded
            if(isempty(app.ust_datavalues))
                return;
            end
            
            % display a-mode
            ust_usspec      = app.ust_usspec;
            selected_ust = app.DropDown_TransducerNumber.ValueIndex;
            amode_signal = squeeze( app.ust_datavalues(selected_ust, :, selected_timestamp) );
            display_amode(app.UIAxes_Amode, ust_usspec.s_vector, amode_signal, 10e3);

            % display the mmode timestamp
            display_mmode_timestamp(app.UIAxes_Mmode, selected_timestamp);

            % check if window is valid
            if(~app.ust_windowconfig(selected_ust).IsSet)
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodewindow'));
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodepeak'));
                uialert(app.UIFigure, 'No window value exist for the current transducer in the loaded window configuration file', 'Warning', 'Icon', 'warning')
                return;
            end

            % peak detect (and get the value in the dropdowns)
            selected_ust        = app.DropDown_TransducerNumber.ValueIndex;
            selected_timestamp  = app.EditField_Timestamp.Value;
            selected_window     = app.ust_windowconfig(selected_ust);
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, selected_window);
            % display amode peak
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);
        end

        % Value changed function: Spinner_MinHeight
        function Spinner_MinHeightValueChanged(app, event)
            % value = app.Spinner_MinHeight.Value;

            % check if us data already loaded
            if(isempty(app.ust_datavalues))
                return;
            end

            % check if window is valid
            if(~app.ust_windowconfig(selected_ust).IsSet)
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodewindow'));
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodepeak'));
                uialert(app.UIFigure, 'No window value exist for the current transducer in the loaded window configuration file', 'Warning', 'Icon', 'warning')
                return;
            end

            % detect the amode peak with current 
            selected_ust        = app.DropDown_TransducerNumber.ValueIndex;
            selected_timestamp  = app.EditField_Timestamp.Value;
            selected_window     = app.ust_windowconfig(selected_ust);
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, selected_window);
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);            
        end

        % Value changed function: Spinner_MinProm
        function Spinner_MinPromValueChanged(app, event)
            %value = app.Spinner_MinProm.Value;
            
            % check if us data already loaded
            if(isempty(app.ust_datavalues))
                return;
            end

            % check if window is valid
            if(~app.ust_windowconfig(selected_ust).IsSet)
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodewindow'));
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodepeak'));
                uialert(app.UIFigure, 'No window value exist for the current transducer in the loaded window configuration file', 'Warning', 'Icon', 'warning')
                return;
            end

            % detect the amode peak with current 
            selected_ust        = app.DropDown_TransducerNumber.ValueIndex;
            selected_timestamp  = app.EditField_Timestamp.Value;
            selected_window     = app.ust_windowconfig(selected_ust);
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, selected_window);
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);            
        end

        % Value changed function: Spinner_MinPeakDiff
        function Spinner_MinPeakDiffValueChanged(app, event)
            % value = app.Spinner_MinPeakDiff.Value;

            % check if us data already loaded
            if(isempty(app.ust_datavalues))
                return;
            end

            % check if window is valid
            if(~app.ust_windowconfig(selected_ust).IsSet)
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodewindow'));
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodepeak'));
                uialert(app.UIFigure, 'No window value exist for the current transducer in the loaded window configuration file', 'Warning', 'Icon', 'warning')
                return;
            end
            
            % detect the amode peak with current 
            selected_ust        = app.DropDown_TransducerNumber.ValueIndex;
            selected_timestamp  = app.EditField_Timestamp.Value;
            selected_window     = app.ust_windowconfig(selected_ust);
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, selected_window);
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);
        end

        % Value changed function: Spinner_MinPeakDist
        function Spinner_MinPeakDistValueChanged(app, event)
            % value = app.Spinner_MinPeakDist.Value;
            
            % check if us data already loaded
            if(isempty(app.ust_datavalues))
                return;
            end

            % check if window is valid
            if(~app.ust_windowconfig(selected_ust).IsSet)
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodewindow'));
                delete(findobj(app.UIAxes_Amode, 'Tag', 'plot_amodepeak'));
                uialert(app.UIFigure, 'No window value exist for the current transducer in the loaded window configuration file', 'Warning', 'Icon', 'warning')
                return;
            end

            % detect the amode peak
            selected_ust        = app.DropDown_TransducerNumber.ValueIndex;
            selected_timestamp  = app.EditField_Timestamp.Value;
            selected_window     = app.ust_windowconfig(selected_ust);
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, selected_window);
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);
        end

        % Value changed function: Spinner_WindowCenter
        function Spinner_WindowCenterValueChanged(app, event)
            % get the value from the spinner
            new_middle    = app.Spinner_WindowCenter.Value;
            current_width = app.Spinner_WindowWidth.Value;

            % get the limit values
            lower_limit = app.Spinner_WindowWidth.Limits(1);
            upper_limit = app.Spinner_WindowWidth.Limits(2);

            % if the new middle yields to upper and lower bound outside the
            % a-mode x-axis limits, ignore the whole process
            if ( new_middle - current_width < lower_limit || ...
                 new_middle + current_width > upper_limit)
                % Revert to the previous valid value (thus "undoing" the change)
                app.Spinner_WindowCenter.Value = app.spinner_windowcenter_valueprev;
                return;
            end

            % If the condition is met (i.e. the values are valid), update the previous value
            app.spinner_windowcenter_valueprev = new_middle;

            % get the necessary global variables
            ust_windowconfig = app.ust_windowconfig;            
            % get the current selected ust
            selected_ust = app.DropDown_TransducerNumber.ValueIndex;
            current_m    = ust_windowconfig(selected_ust).Middle;
            current_lb   = ust_windowconfig(selected_ust).LowerBound;
            current_ub   = ust_windowconfig(selected_ust).UpperBound;

            % update the ust_windowconfig structure
            ust_windowconfig(selected_ust).Middle     = new_middle;
            ust_windowconfig(selected_ust).LowerBound = new_middle - current_width;
            ust_windowconfig(selected_ust).UpperBound = new_middle + current_width;

            % update window in both amode and mmode display
            lb = ust_windowconfig(selected_ust).LowerBound;
            m  = ust_windowconfig(selected_ust).Middle;
            ub = ust_windowconfig(selected_ust).UpperBound;
            display_amode_window(app.UIAxes_Amode, lb, m, ub)
            display_mmode_window(app.UIAxes_Mmode, lb, m, ub);

            % detect the amode peak
            selected_timestamp  = app.EditField_Timestamp.Value;
            selected_window     = ust_windowconfig(selected_ust);
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, selected_window);
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);
            
            % store the current ust_windowconfig
            app.ust_windowconfig = ust_windowconfig;
        end

        % Value changed function: Spinner_WindowWidth
        function Spinner_WindowWidthValueChanged(app, event)
            % get the value from the spinner
            current_middle = app.Spinner_WindowCenter.Value;
            new_width      = app.Spinner_WindowWidth.Value;

            % get the limit values
            lower_limit = app.Spinner_WindowWidth.Limits(1);
            upper_limit = app.Spinner_WindowWidth.Limits(2);

            % if the new width yields to upper and lower bound outside the
            % a-mode x-axis limits, ignore the whole process
            if ( current_middle - new_width < lower_limit || ...
                 current_middle + new_width > upper_limit)
                % Revert to the previous valid value (thus "undoing" the change)
                app.Spinner_WindowWidth.Value = app.spinner_windowwidth_valueprev;
                return;
            end

            % If the condition is met (i.e. the values are valid), update the previous value
            app.spinner_windowwidth_valueprev = new_width;
            
            % get the necessary global variables
            ust_windowconfig = app.ust_windowconfig;            
            % get the current selected ust
            selected_ust = app.DropDown_TransducerNumber.ValueIndex;
            current_m    = ust_windowconfig(selected_ust).Middle;
            current_lb   = ust_windowconfig(selected_ust).LowerBound;
            current_ub   = ust_windowconfig(selected_ust).UpperBound;

            % update the ust_windowconfig structure
            ust_windowconfig(selected_ust).LowerBound = current_middle - new_width;
            ust_windowconfig(selected_ust).UpperBound = current_middle + new_width;

            % update window in both amode and mmode display
            lb = ust_windowconfig(selected_ust).LowerBound;
            m  = ust_windowconfig(selected_ust).Middle;
            ub = ust_windowconfig(selected_ust).UpperBound;
            display_amode_window(app.UIAxes_Amode, lb, m, ub)
            display_mmode_window(app.UIAxes_Mmode, lb, m, ub);

            % detect the amode peak
            selected_timestamp  = app.EditField_Timestamp.Value;
            selected_window     = ust_windowconfig(selected_ust);
            [peak_mm, peak_val] = amode_peak_detect(app, selected_ust, selected_timestamp, selected_window);
            display_amode_peak(app.UIAxes_Amode, peak_mm, peak_val);

            % store the current ust_windowconfig
            app.ust_windowconfig = ust_windowconfig;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_Main = uigridlayout(app.UIFigure);
            app.GridLayout_Graphs = uigridlayout(app.GridLayout_Main);
            app.UIAxes_Amode = uiaxes(app.GridLayout_Graphs);
            title(app.UIAxes_Amode, 'A-mode Signal')
            xlabel(app.UIAxes_Amode, 'Depth (mm)')
            ylabel(app.UIAxes_Amode, 'Amplitude')
            zlabel(app.UIAxes_Amode, 'Z')
            app.UIAxes_Mmode = uiaxes(app.GridLayout_Graphs);
            title(app.UIAxes_Mmode, 'M-Mode Image')
            xlabel(app.UIAxes_Mmode, 'Timestamp')
            ylabel(app.UIAxes_Mmode, 'Depth (mm)')
            zlabel(app.UIAxes_Mmode, 'Z')
            app.GridLayout_Slider = uigridlayout(app.GridLayout_Graphs);
            app.Slider_Timestamp = uislider(app.GridLayout_Slider);
            app.Slider_Timestamp.Limits = [1 1000];
            app.Slider_Timestamp.ValueChangingFcn = createCallbackFcn(app, @Slider_TimestampValueChanging, true);
            app.Slider_Timestamp.Value = 1;
            app.GridLayout_Menu = uigridlayout(app.GridLayout_Main);
            app.TransducerDropDownLabel = uilabel(app.GridLayout_Menu);
            app.TransducerDropDownLabel.Text = 'Transducer';
            app.DropDown_TransducerNumber = uidropdown(app.GridLayout_Menu);
            app.DropDown_TransducerNumber.Items = {};
            app.DropDown_TransducerNumber.ValueChangedFcn = createCallbackFcn(app, @DropDown_TransducerNumberValueChanged, true);
            app.DropDown_TransducerNumber.Value = {};
            app.CenterLabel = uilabel(app.GridLayout_Menu);
            app.CenterLabel.Text = 'Center';
            app.Spinner_WindowCenter = uispinner(app.GridLayout_Menu);
            app.Spinner_WindowCenter.Step = 0.2;
            app.Spinner_WindowCenter.Limits = [0 Inf];
            app.Spinner_WindowCenter.ValueChangedFcn = createCallbackFcn(app, @Spinner_WindowCenterValueChanged, true);
            app.WidthLabel = uilabel(app.GridLayout_Menu);
            app.WidthLabel.Text = 'Width';
            app.Spinner_WindowWidth = uispinner(app.GridLayout_Menu);
            app.Spinner_WindowWidth.Step = 0.2;
            app.Spinner_WindowWidth.Limits = [0 Inf];
            app.Spinner_WindowWidth.ValueChangedFcn = createCallbackFcn(app, @Spinner_WindowWidthValueChanged, true);
            app.MinHeightSpinnerLabel = uilabel(app.GridLayout_Menu);
            app.MinHeightSpinnerLabel.Text = 'Min Height';
            app.Spinner_MinHeight = uispinner(app.GridLayout_Menu);
            app.Spinner_MinHeight.Step = 20;
            app.Spinner_MinHeight.Limits = [0 Inf];
            app.Spinner_MinHeight.ValueChangedFcn = createCallbackFcn(app, @Spinner_MinHeightValueChanged, true);
            app.Spinner_MinHeight.Value = 100;
            app.MinPromLabel = uilabel(app.GridLayout_Menu);
            app.MinPromLabel.Text = 'Min Prom';
            app.Spinner_MinProm = uispinner(app.GridLayout_Menu);
            app.Spinner_MinProm.Step = 20;
            app.Spinner_MinProm.Limits = [0 Inf];
            app.Spinner_MinProm.ValueChangedFcn = createCallbackFcn(app, @Spinner_MinPromValueChanged, true);
            app.Spinner_MinProm.Value = 100;
            app.MinPeakDiffLabel = uilabel(app.GridLayout_Menu);
            app.MinPeakDiffLabel.Text = 'Min Peak Diff';
            app.Spinner_MinPeakDiff = uispinner(app.GridLayout_Menu);
            app.Spinner_MinPeakDiff.Step = 0.2;
            app.Spinner_MinPeakDiff.Limits = [0 Inf];
            app.Spinner_MinPeakDiff.ValueChangedFcn = createCallbackFcn(app, @Spinner_MinPeakDiffValueChanged, true);
            app.MinPeakDistSpinnerLabel = uilabel(app.GridLayout_Menu);
            app.MinPeakDistSpinnerLabel.Text = 'Min Peak Dist.';
            app.Spinner_MinPeakDist = uispinner(app.GridLayout_Menu);
            app.Spinner_MinPeakDist.Step = 0.2;
            app.Spinner_MinPeakDist.Limits = [0 Inf];
            app.Spinner_MinPeakDist.ValueChangedFcn = createCallbackFcn(app, @Spinner_MinPeakDistValueChanged, true);
            app.TrialDropDownLabel = uilabel(app.GridLayout_Menu);
            app.TrialDropDownLabel.Text = 'Trial';
            app.DropDown_Trial = uidropdown(app.GridLayout_Menu);
            app.DropDown_Trial.Items = {};
            app.DropDown_Trial.ValueChangedFcn = createCallbackFcn(app, @DropDown_TrialValueChanged, true);
            app.DropDown_Trial.Value = {};
            app.RecordLabel = uilabel(app.GridLayout_Menu);
            app.RecordLabel.Text = 'Measurement';
            app.DropDown_Measurement = uidropdown(app.GridLayout_Menu);
            app.DropDown_Measurement.Items = {};
            app.DropDown_Measurement.Value = {};
            app.Button_LoadUSData = uibutton(app.GridLayout_Menu, 'push');
            app.Button_LoadUSData.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadUSDataPushed, true);
            app.Button_LoadUSData.Text = 'Load US Data';
            app.GridLayout = uigridlayout(app.GridLayout_Menu);
            app.Button_TransducerPrev = uibutton(app.GridLayout, 'push');
            app.Button_TransducerPrev.ButtonPushedFcn = createCallbackFcn(app, @Button_TransducerPrevPushed, true);
            app.Button_TransducerPrev.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'arrow-small-left.png');
            app.Button_TransducerPrev.Text = '';
            app.Button_TransducerNext = uibutton(app.GridLayout, 'push');
            app.Button_TransducerNext.ButtonPushedFcn = createCallbackFcn(app, @Button_TransducerNextPushed, true);
            app.Button_TransducerNext.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'arrow-small-right.png');
            app.Button_TransducerNext.Text = '';
            app.GridLayout4 = uigridlayout(app.GridLayout_Menu);
            app.Button_TimestampPlay = uibutton(app.GridLayout4, 'push');
            app.Button_TimestampPlay.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'play.png');
            app.Button_TimestampPlay.Text = 'Play';
            app.Button_TimestampPause = uibutton(app.GridLayout4, 'push');
            app.Button_TimestampPause.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'pause.png');
            app.Button_TimestampPause.Text = 'Pause';
            app.Label_WindowConf = uilabel(app.GridLayout_Menu);
            app.Label_WindowConf.Text = 'Window Conf.';
            app.Button_DepthDetect = uibutton(app.GridLayout_Menu, 'push');
            app.Button_DepthDetect.Text = 'Detect Depth';
            app.Button_DepthExport = uibutton(app.GridLayout_Menu, 'push');
            app.Button_DepthExport.Text = 'Export Depth';
            app.GridLayout6 = uigridlayout(app.GridLayout_Menu);
            app.EditField_Timestamp = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_Timestamp.Limits = [1 Inf];
            app.EditField_Timestamp.Value = 1;
            app.Label_Timestamp = uilabel(app.GridLayout6);
            app.Label_Timestamp.Text = '1000';
            app.Label = uilabel(app.GridLayout6);
            app.Label.Text = '/';
            app.Button_TimestampPrev = uibutton(app.GridLayout6, 'push');
            app.Button_TimestampPrev.ButtonPushedFcn = createCallbackFcn(app, @Button_TimestampPrevPushed, true);
            app.Button_TimestampPrev.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'minus-small.png');
            app.Button_TimestampPrev.Text = '';
            app.Button_TimestampNext = uibutton(app.GridLayout6, 'push');
            app.Button_TimestampNext.ButtonPushedFcn = createCallbackFcn(app, @Button_TimestampNextPushed, true);
            app.Button_TimestampNext.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'plus-small.png');
            app.Button_TimestampNext.Text = '';
            app.AnimationLabel_2 = uilabel(app.GridLayout_Menu);
            app.AnimationLabel_2.Text = 'Animation';
            app.TimestampLabel = uilabel(app.GridLayout_Menu);
            app.TimestampLabel.Text = 'Timestamp';
            app.GridLayout7 = uigridlayout(app.GridLayout_Menu);
            app.Button_WindowExport = uibutton(app.GridLayout7, 'push');
            app.Button_WindowExport.ButtonPushedFcn = createCallbackFcn(app, @Button_WindowExportPushed, true);
            app.Button_WindowExport.Text = 'Export Window';
            app.Button_ResetWindowConf = uibutton(app.GridLayout7, 'push');
            app.Button_ResetWindowConf.ButtonPushedFcn = createCallbackFcn(app, @Button_ResetWindowConfPushed, true);
            app.Button_ResetWindowConf.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'refresh.png');
            app.Button_ResetWindowConf.Text = '';
            app.DropDown_SelectWindowConf = uidropdown(app.GridLayout_Menu);
            app.DropDown_SelectWindowConf.Items = {};
            app.DropDown_SelectWindowConf.ValueChangedFcn = createCallbackFcn(app, @DropDown_SelectWindowConfValueChanged, true);
            app.DropDown_SelectWindowConf.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = depthdetection_toolbox2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end